classdef eyeCalibration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        VideoCollectionTab              matlab.ui.container.Tab
        CameraParametersLabel           matlab.ui.control.Label
        PresetsDropDownLabel            matlab.ui.control.Label
        PresetsDropDown                 matlab.ui.control.DropDown
        CameraResolutionEditFieldLabel  matlab.ui.control.Label
        CameraResolutionEditField       matlab.ui.control.NumericEditField
        BoxSizeEditFieldLabel           matlab.ui.control.Label
        BoxSizeEditField                matlab.ui.control.NumericEditField
        BoxOffsetEditFieldLabel         matlab.ui.control.Label
        BoxOffsetEditField              matlab.ui.control.NumericEditField
        CameraResolutionEditField_2     matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        SetupCamerasButton              matlab.ui.control.Button
        CollectDataButton               matlab.ui.control.Button
        NumberofframestocollectEditFieldLabel  matlab.ui.control.Label
        NumberofframestocollectEditField  matlab.ui.control.NumericEditField
        OfflineEyeTrackingTab           matlab.ui.container.Tab
        RadiusPupilEditFieldLabel       matlab.ui.control.Label
        RadiusPupilEditField            matlab.ui.control.NumericEditField
        XPaddingEditFieldLabel          matlab.ui.control.Label
        XPaddingEditField               matlab.ui.control.NumericEditField
        YPaddingEditFieldLabel          matlab.ui.control.Label
        YPaddingEditField               matlab.ui.control.NumericEditField
        RadiusCornealReflection1EditFieldLabel  matlab.ui.control.Label
        RadiusCornealReflection1EditField  matlab.ui.control.NumericEditField
        RadiusCornealReflection2Label   matlab.ui.control.Label
        RadiusCornealReflection2EditField  matlab.ui.control.NumericEditField
        MinimumFeaturesEditFieldLabel   matlab.ui.control.Label
        MinimumFeaturesEditField        matlab.ui.control.NumericEditField
        radiiCR1EditField_2             matlab.ui.control.NumericEditField
        radiiCR2EditField_2             matlab.ui.control.NumericEditField
        ManualPupilLocationSelectionCheckBox  matlab.ui.control.CheckBox
        ImproveImageContrastCheckBox    matlab.ui.control.CheckBox
        TestParametersButton            matlab.ui.control.Button
        RunAnalysisButton               matlab.ui.control.Button
        edgeThresh1EditFieldLabel       matlab.ui.control.Label
        edgeThresh1EditField            matlab.ui.control.NumericEditField
        edgeThresh2EditFieldLabel       matlab.ui.control.Label
        edgeThresh2EditField            matlab.ui.control.NumericEditField
        PlotAnalysisCheckBox            matlab.ui.control.CheckBox
        debugCheckBox                   matlab.ui.control.CheckBox
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
        EyeAnalysisTab                  matlab.ui.container.Tab
        findmagscaleVelButton           matlab.ui.control.Button
        MagnetThreshEditFieldLabel      matlab.ui.control.Label
        MagnetThreshEditField           matlab.ui.control.NumericEditField
        VideoThreshEditFieldLabel       matlab.ui.control.Label
        VideoThreshEditField            matlab.ui.control.NumericEditField
        CalibrationStimFrequencyEditFieldLabel  matlab.ui.control.Label
        CalibrationStimFrequencyEditField  matlab.ui.control.NumericEditField
        LeftEyeCheckBox                 matlab.ui.control.CheckBox
        Camera2TimestampsCheckBox       matlab.ui.control.CheckBox
        PLOT_DesaccededTracesButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        vars % Description
    end
    methods (Access = private)
        
        function results = setupVideo(app, roiInitial)
            % make sure that there are no other connection to other video input
            imaqreset;
            
            % Videoinput makes sure that the connection between PC and camera exists.
            vid(1) = videoinput('winvideo',1,'MJPG_1280x1024');
            vid(2) = videoinput('winvideo',2,'MJPG_1280x1024');
            
            % Set up the camera
            set(vid,'FramesPerTrigger',1);
            set(vid,'TriggerRepeat',Inf);
            
            % Instead of default config, use our 'vid' parameters file.
            % Keep recording forever until we stop it.
            triggerconfig(vid,'manual');
            
            % Get a grayscale image
            set(vid,'ReturnedColorSpace','grayscale');
            
            %% Set exposure and gain
            for i = 1:2
                src = getselectedsource(vid(i));
                src.Exposure = -10; % changing the exposure changes the frames per second. Higher exposure == lower frames per second
                frameRates = set(src, 'FrameRate');
                src.FrameRate = frameRates{1}; % Highest frame rate = 30 fps
            end
            
            set(vid(1), 'ROIPosition',roiInitial);
            set(vid(2), 'ROIPosition',roiInitial);
        end
        
        function vidAmp = vidTimeFcn(app, tvid,vidH,freq,tscale)
            varsTemp = [sin(2*pi*freq*tvid(:)/tscale) cos(2*pi*freq*tvid(:)/tscale) ones(size(tvid(:)))];
            yTemp = [0; diff(vidH)]/mean(diff(tvid));
            mask = abs(yTemp)<100;
            bVidTemp = regress(yTemp(mask), varsTemp(mask,:));
            vidAmp = -sqrt(bVidTemp(1)^2+bVidTemp(2)^2);
        end
    
        function findmagscaleVelAPP(app)

            magthresh = app.MagnetThreshEditField.Value;
            vidthresh = app.VideoThreshEditField.Value;
            freq = app.CalibrationStimFrequencyEditField.Value;
            
            
            %%%%% LOAD MAGNET
            pathname = cd;
            [~, filenameroot]= fileparts(pathname);
            fullfilename = fullfile(pathname,[filenameroot '.smr']);
            
            if app.LeftEyeCheckBox.Value
                % Left Eye (default)
                app.vars.magnetData = importSpike(fullfilename,[4 5 6 10]);
            else
                % Right Eye
                app.vars.magnetData = importSpike(fullfilename,[4 7 8 10]);
            end
            
            %%%%% LOAD VIDEO
            A = load(fullfile(pathname, 'videoresults.mat'));
            app.vars.vidResults = A.results;
            [app.vars.vidH, app.vars.vidV, ~] = calceyeangle(app.vars.vidResults);
            
            if app.Camera2TimestampsCheckBox.Value
                % Camera 2 (default)
                app.vars.tvid = app.vars.vidResults.time2;
            else
                % Camera 1
                app.vars.tvid = app.vars.vidResults.time1;
            end
            app.vars.tvid = app.vars.tvid-app.vars.tvid(1);
            [app.vars.tscale, temp] = fminsearchbnd(@(x)vidTimeFcn(app, app.vars.tvid,app.vars.vidH,freq,x),1,.7, 1.4);
            app.vars.tvid = app.vars.tvid/app.vars.tscale;%.995;
            app.vars.samplerate_Video = 1/mean(diff(app.vars.tvid));
            
            
            %%%%% Subselect and desaccade magnet
            app.vars.lightpulse = app.vars.magnetData(end).data;
            app.vars.seg(1) = app.vars.lightpulse(1);
            app.vars.seg(2) = app.vars.seg(1)+app.vars.tvid(end);
            
            app.vars.magnetSeg = resettime(datseg(app.vars.magnetData,app.vars.seg));
            app.vars.samplerate_Magnet = app.vars.magnetSeg(1).samplerate;
            app.vars.magnetSeg(1).data = smooth([diff(smooth(app.vars.magnetSeg(1).data,25)); 0]*app.vars.samplerate_Magnet,25); % Convert to velocity
            app.vars.magnetSeg(1).units = 'deg/s'; app.vars.magnetSeg(1).chanlabel = 'hhvel';
            
            % Filter Magnet Position Traces with 100Hz low pass filter
            app.vars.mag1 = datlowpass(app.vars.magnetSeg(2),100);
            app.vars.mag2 = datlowpass(app.vars.magnetSeg(3),100);
            app.vars.tmag = dattime(app.vars.mag1);
            
            % Upsample Video Traces
            app.vars.vidH_upsample = interp1(app.vars.tvid,app.vars.vidH,app.vars.tmag(:),'linear');
            app.vars.vidH_upsample = inpaint_nans(app.vars.vidH_upsample);
            
            % Create Velocity Traces
            app.vars.mag1Vel = [diff(app.vars.mag1.data); 0]*app.vars.samplerate_Magnet;
            app.vars.mag2Vel = [diff(app.vars.mag2.data); 0]*app.vars.samplerate_Magnet;
            app.vars.vidVel = [diff(app.vars.vidH_upsample); 0]*app.vars.samplerate_Magnet;
            
            % Desaccade Velocity Traces
            app.vars.mag1_saccademask = ~isnan(desaccade(app.vars.mag1Vel,app.vars.samplerate_Magnet,magthresh,0));
            app.vars.mag2_saccademask = ~isnan(desaccade(app.vars.mag2Vel,app.vars.samplerate_Magnet,magthresh,0));
            app.vars.vid_saccademask = ~isnan(desaccade(app.vars.vidVel,app.vars.samplerate_Magnet,vidthresh,0));
            
            % Smooth Velocity Traces (For plotting ONLY)
            app.vars.mag1Velplot = smooth(app.vars.mag1Vel,50);
            app.vars.mag2Velplot = smooth(app.vars.mag2Vel,50);
            app.vars.vidVelplot = smooth(inpaint_nans(app.vars.vidVel),50,'moving');
            
            
            %%%%% SINE FIT FOR MAGET AND VIDEO
            y1 = sin(2*pi*freq*app.vars.tmag(:));
            y2 = cos(2*pi*freq*app.vars.tmag(:));
            const = ones(size(y1));
            app.vars.vars = [y1 y2 const];
            
            % ------------ Chair -------------
            [app.vars.bHead,~,~,~,~] = regress(app.vars.magnetSeg(1).data, app.vars.vars);
            headAmp = sqrt(app.vars.bHead(1)^2+app.vars.bHead(2)^2);
            app.vars.headAmp = headAmp;
            headPhase = rad2deg(atan2(app.vars.bHead(2),app.vars.bHead(1)));
            
            % ------------ MAGNET 1------------
            [app.vars.bMag1,~,~,~,stat] = regress(app.vars.mag1Vel(app.vars.mag1_saccademask), app.vars.vars(app.vars.mag1_saccademask,:));
            mag1Amp = sqrt(app.vars.bMag1(1)^2+app.vars.bMag1(2)^2);
            app.vars.mag1Amp = mag1Amp;
            mag1Phase = mod((rad2deg(atan2(app.vars.bMag1(2),app.vars.bMag1(1))) - headPhase),360)-180;
            r2mag1 = stat(1);
            app.vars.r2mag1 = r2mag1;
            
            % ------------ MAGNET 2------------
            [app.vars.bMag2,~,~,~,stat] = regress(app.vars.mag2Vel(app.vars.mag2_saccademask), app.vars.vars(app.vars.mag2_saccademask,:));
            mag2Amp = sqrt(app.vars.bMag2(1)^2+app.vars.bMag2(2)^2);
            app.vars.mag2Amp = mag2Amp;
            mag2Phase = mod((rad2deg(atan2(app.vars.bMag2(2),app.vars.bMag2(1))) - headPhase),360)-180;
            r2mag2 = stat(1);
            app.vars.r2mag2 = r2mag2;
            
            % ------------ VIDEO ------------
            [app.vars.bVid,~,~,~,stat] = regress(app.vars.vidVel(app.vars.vid_saccademask), app.vars.vars(app.vars.vid_saccademask,:));
            vidAmp = sqrt(app.vars.bVid(1)^2+app.vars.bVid(2)^2);
            app.vars.vidAmp = vidAmp;
            vidPhase = rad2deg(atan2(app.vars.bVid(2), app.vars.bVid(1)));
            r2vid = stat(1);
            app.vars.r2vid = r2vid;
            
            
            %%%%% SAVE SCALE FACTOR
            % Add term for 180 deg phase - negate?
            if r2mag1>r2mag2
                scaleCh1 = vidAmp/mag1Amp * (2*(abs(mag1Phase)<90)-1); % Change sign if needed
                app.vars.scaleCh1 = scaleCh1;
                scaleCh2 = 0;
                app.vars.scaleCh2 = scaleCh2;
            else
                scaleCh1 = 0;
                app.vars.scaleC1 = scaleCh1;
                scaleCh2 = vidAmp/mag2Amp * (2*(abs(mag2Phase)<90)-1); % Change sign if needed
                app.vars.scaleCh2 = scaleCh2;
            end
            fprintf('Scale: Chan 1 = %.2f\nScale: Chan 2 = %.2f\n',app.vars.scaleCh1, app.vars.scaleCh2)
            fprintf('r^2: Chan 1 = %.4f\nr^2: Chan 2 = %.4f\n',r2mag1, r2mag2)
            save(fullfile(cd, [filenameroot '.mat']),'scaleCh1', 'scaleCh2',...
                'vidAmp','mag1Amp','mag1Phase','mag2Amp','mag2Phase',...
                'r2mag1','r2mag2','r2vid','vidthresh','magthresh','freq');   
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SetupCamerasButton
        function SetupCamerasButtonPushed(app, event)
            % get parameters from gui
            res = [app.CameraResolutionEditField.Value app.CameraResolutionEditField_2.Value];
            boxSize = app.BoxSizeEditField.Value;
            boxOffset = app.BoxOffsetEditField.Value;
            
            % [x_offest, y_offset, width, length]
            roiInitial = [(res-boxSize)/2 boxSize boxSize]+[0 boxOffset 0 0];
            
            
            % Set the camera specific parameters. (frame rate, exposure, etc...)
            vid = setupvideo_new2019(roiInitial); pause(.1)
            
            % Show preview until figure closed
            % img1 and img2 are 'cubes' or 'stacks' of images (not from experiment)
            [img1, img2] = eyecam(vid); % ***Run "eyecam(vid);" to see preview at any point ***%
            
            % Prompt for ROI around one eye - first click on camera image from the left
            % side, then click-drag to select rectangle
            vid = setROI(vid,img1, img2, res, boxSize, boxOffset);
            
        end

        % Button pushed function: CollectDataButton
        function CollectDataButtonPushed(app, event)
            % Prompt User for calibration folder location
            folder = uigetdir(cd, 'Select Calibration Folder');
            
            if exist(fullfile(folder,'img1.tiff'),'file')
                warning('Overwrite')
                delete(fullfile(pwd, 'img1.tiff'),fullfile(pwd,'img2.tiff'))
            end
            
            close all
            eyetrackon = 1;
            nframes = 30*120; % 30*nseconds
            eyecam(vid,eyetrackon,nframes); % results = time stamps, images are saves as img1 and 2
            warning on
        end

        % Button pushed function: TestParametersButton
        function TestParametersButtonPushed(app, event)
            radiiPupil = app.RadiusPupilEditField.Value;
            xpad = app.XPaddingEditField.Value;
            ypad = app.YPaddingEditField.Value;
            radiiCR1 = [app.RadiusCornealReflection1EditField.Value app.radiiCR1EditField_2.Value];
            radiiCR2 = [app.RadiusCornealReflection2EditField.Value app.radiiCR2EditField_2.Value];
            minfeatures = app.MinimumFeaturesEditField.Value;
            imAdjust = app.ImproveImageContrastCheckBox.Value;
            manual = app.ManualPupilLocationSelectionCheckBox.Value;
            
            videoTest(radiiPupil,[xpad ypad],radiiCR1,radiiCR2,minfeatures,'ImAdjust',imAdjust,'Manual',manual)
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
            % Locate pupil and CR
            eyeAnalysis
            
            % Save results
            saveas(1,'eyetrack1.jpg');
            saveas(2,'eyetrack2.jpg');
        end

        % Button pushed function: findmagscaleVelButton
        function findmagscaleVelButtonPushed(app, event)
            findmagscaleVelAPP(app);       
        end

        % Button pushed function: PLOT_DesaccededTracesButton
        function PLOT_DesaccededTracesButtonPushed(app, event)
            findmagscaleVelAPP(app);
            desacPLot = tight_subplot(3,1,[.025 .025],[.025 .025],[.015 .01]);
            
            axes(desacPLot(1));
            title('Magnet 1 Desaccading, Unscaled'); hold on;
            plot(app.vars.tmag, app.vars.mag1Velplot,'k'); 
            app.vars.mag1Velplot(~app.vars.mag1_saccademask) = NaN;
            plot(app.vars.tmag, app.vars.mag1Velplot,'b', 'LineWidth', .5); 
            plot(app.vars.tmag,app.vars.vars*app.vars.bMag1,'r-', 'LineWidth', 1);
            ylim([-2 2]);
            text(1, 1.6, sprintf('Amp = %.3f\nr^2 = %.3f\nScale Factor = %.3f',app.vars.mag1Amp, app.vars.r2mag1, app.vars.scaleCh1),'BackgroundColor','w')
            yticklabels(yticks)
            
            axes(desacPLot(2));
            title('Magnet 2 Desaccading, Unscaled'); hold on;
            plot(app.vars.tmag, app.vars.mag2Velplot,'k');  
            app.vars.mag2Velplot(~app.vars.mag2_saccademask) = NaN;
            plot(app.vars.tmag, app.vars.mag2Velplot,'b', 'LineWidth', .5);  
            plot(app.vars.tmag,app.vars.vars*app.vars.bMag2,'r-', 'LineWidth', 1);
            ylim([-2 2]);
            text(1, 1.6, sprintf('Amp = %.3f\nr^2 = %.3f\nScale Factor = %.3f',app.vars.mag2Amp, app.vars.r2mag2, app.vars.scaleCh2),'BackgroundColor','w')
            yticklabels(yticks)
            
            axes(desacPLot(3));
            title('Video Desaccading'); hold on;
            plot(app.vars.tmag, app.vars.vidVelplot,'k');  
            app.vars.vidVelplot(~app.vars.vid_saccademask) = NaN;
            plot(app.vars.tmag, app.vars.vidVelplot,'b', 'LineWidth', .5);  
            plot(app.vars.tmag, app.vars.vars*app.vars.bVid,'r-', 'LineWidth', 1);
            ylim([-25 25]);
            text(1, 20, sprintf('Amp = %.3f\nr^2 = %.3f',app.vars.vidAmp, app.vars.r2vid),'BackgroundColor','w')
            yticklabels(yticks)
            
            % Cosmetics
            xlabel('s')
            linkaxes(desacPLot, 'x')
            xticks(0:5:max(xticks))
            xticklabels(0:5:max(xticks))
            xlim([0, 40])
            box off
            
            % Save Figures
            savefig('Calib_Desaccading_Summary.fig')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.VideoCollectionTab = uitab(app.TabGroup);
            app.VideoCollectionTab.Title = 'Video Collection';
            app.CameraParametersLabel = uilabel(app.VideoCollectionTab);
            app.CameraParametersLabel.Text = 'Camera Parameters';
            app.PresetsDropDownLabel = uilabel(app.VideoCollectionTab);
            app.PresetsDropDownLabel.Text = 'Presets';
            app.PresetsDropDown = uidropdown(app.VideoCollectionTab);
            app.PresetsDropDown.Items = {'ELP', 'Custom'};
            app.PresetsDropDown.Value = 'ELP';
            app.CameraResolutionEditFieldLabel = uilabel(app.VideoCollectionTab);
            app.CameraResolutionEditFieldLabel.Text = 'Camera Resolution';
            app.CameraResolutionEditField = uieditfield(app.VideoCollectionTab, 'numeric');
            app.CameraResolutionEditField.Tooltip = {'Camera Resolution'};
            app.CameraResolutionEditField.Value = 1280;
            app.BoxSizeEditFieldLabel = uilabel(app.VideoCollectionTab);
            app.BoxSizeEditFieldLabel.Text = 'Box Size';
            app.BoxSizeEditField = uieditfield(app.VideoCollectionTab, 'numeric');
            app.BoxSizeEditField.Tooltip = {'Length and Width of the square during initial view. (a crop of the resolution)'};
            app.BoxSizeEditField.Value = 500;
            app.BoxOffsetEditFieldLabel = uilabel(app.VideoCollectionTab);
            app.BoxOffsetEditFieldLabel.Text = 'Box Offset';
            app.BoxOffsetEditField = uieditfield(app.VideoCollectionTab, 'numeric');
            app.BoxOffsetEditField.Tooltip = {'Vertical Offset (pixels) below the line at (res(2)-box)/2 pixels above the bottom of the camera''s full field of view (indicated by'; 'res)'};
            app.CameraResolutionEditField_2 = uieditfield(app.VideoCollectionTab, 'numeric');
            app.CameraResolutionEditField_2.Value = 1024;
            app.UIAxes = uiaxes(app.VideoCollectionTab);
            title(app.UIAxes, 'Camera 1')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.VideoCollectionTab);
            title(app.UIAxes_2, 'Camera 1')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.SetupCamerasButton = uibutton(app.VideoCollectionTab, 'push');
            app.SetupCamerasButton.ButtonPushedFcn = createCallbackFcn(app, @SetupCamerasButtonPushed, true);
            app.SetupCamerasButton.Text = 'Setup Cameras';
            app.CollectDataButton = uibutton(app.VideoCollectionTab, 'push');
            app.CollectDataButton.ButtonPushedFcn = createCallbackFcn(app, @CollectDataButtonPushed, true);
            app.CollectDataButton.Text = 'Collect Data';
            app.NumberofframestocollectEditFieldLabel = uilabel(app.VideoCollectionTab);
            app.NumberofframestocollectEditFieldLabel.Text = 'Number of frames to collect';
            app.NumberofframestocollectEditField = uieditfield(app.VideoCollectionTab, 'numeric');
            app.NumberofframestocollectEditField.Tooltip = {'@ 30fps for 120 seconds'};
            app.NumberofframestocollectEditField.Value = 3600;
            app.OfflineEyeTrackingTab = uitab(app.TabGroup);
            app.OfflineEyeTrackingTab.Title = 'Offline Eye Tracking';
            app.RadiusPupilEditFieldLabel = uilabel(app.OfflineEyeTrackingTab);
            app.RadiusPupilEditFieldLabel.Text = 'Radius: Pupil';
            app.RadiusPupilEditField = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.RadiusPupilEditField.Tooltip = {'radius in pixels'};
            app.RadiusPupilEditField.Value = 50;
            app.XPaddingEditFieldLabel = uilabel(app.OfflineEyeTrackingTab);
            app.XPaddingEditFieldLabel.Text = 'X - Padding';
            app.XPaddingEditField = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.XPaddingEditField.Tooltip = {'X Padding around Pupil in pixels'};
            app.XPaddingEditField.Value = 100;
            app.YPaddingEditFieldLabel = uilabel(app.OfflineEyeTrackingTab);
            app.YPaddingEditFieldLabel.Text = 'Y - Padding';
            app.YPaddingEditField = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.YPaddingEditField.Tooltip = {'Y Padding around Pupil in pixels'};
            app.YPaddingEditField.Value = 40;
            app.RadiusCornealReflection1EditFieldLabel = uilabel(app.OfflineEyeTrackingTab);
            app.RadiusCornealReflection1EditFieldLabel.Text = 'Radius: Corneal Reflection 1 ';
            app.RadiusCornealReflection1EditField = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.RadiusCornealReflection1EditField.Value = 6;
            app.RadiusCornealReflection2Label = uilabel(app.OfflineEyeTrackingTab);
            app.RadiusCornealReflection2Label.Text = 'Radius: Corneal Reflection 2';
            app.RadiusCornealReflection2EditField = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.RadiusCornealReflection2EditField.Value = 6;
            app.MinimumFeaturesEditFieldLabel = uilabel(app.OfflineEyeTrackingTab);
            app.MinimumFeaturesEditFieldLabel.Text = 'Minimum Features';
            app.MinimumFeaturesEditField = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.MinimumFeaturesEditField.Tooltip = {'Fraction of features needed for pupil detection'};
            app.MinimumFeaturesEditField.Value = 0.5;
            app.radiiCR1EditField_2 = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.radiiCR1EditField_2.Value = 12;
            app.radiiCR2EditField_2 = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.radiiCR2EditField_2.Value = 12;
            app.ManualPupilLocationSelectionCheckBox = uicheckbox(app.OfflineEyeTrackingTab);
            app.ManualPupilLocationSelectionCheckBox.Text = 'Manual Pupil Location Selection';
            app.ManualPupilLocationSelectionCheckBox.Value = true;
            app.ImproveImageContrastCheckBox = uicheckbox(app.OfflineEyeTrackingTab);
            app.ImproveImageContrastCheckBox.Text = 'Improve Image Contrast';
            app.ImproveImageContrastCheckBox.Value = true;
            app.TestParametersButton = uibutton(app.OfflineEyeTrackingTab, 'push');
            app.TestParametersButton.ButtonPushedFcn = createCallbackFcn(app, @TestParametersButtonPushed, true);
            app.TestParametersButton.Text = 'Test Parameters';
            app.RunAnalysisButton = uibutton(app.OfflineEyeTrackingTab, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.edgeThresh1EditFieldLabel = uilabel(app.OfflineEyeTrackingTab);
            app.edgeThresh1EditFieldLabel.Text = 'edgeThresh1';
            app.edgeThresh1EditField = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.edgeThresh1EditField.Tooltip = {'Initial gradient threshold of pupil edge detection for cam 1'};
            app.edgeThresh1EditField.Value = 35;
            app.edgeThresh2EditFieldLabel = uilabel(app.OfflineEyeTrackingTab);
            app.edgeThresh2EditFieldLabel.Text = 'edgeThresh2';
            app.edgeThresh2EditField = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.edgeThresh2EditField.Tooltip = {'Initial gradient threshold of pupil edge detection for cam 2'; ''};
            app.edgeThresh2EditField.Value = 35;
            app.PlotAnalysisCheckBox = uicheckbox(app.OfflineEyeTrackingTab);
            app.PlotAnalysisCheckBox.Text = 'Plot Analysis';
            app.PlotAnalysisCheckBox.Value = true;
            app.debugCheckBox = uicheckbox(app.OfflineEyeTrackingTab);
            app.debugCheckBox.Text = 'debug';
            app.UIAxes2 = uiaxes(app.OfflineEyeTrackingTab);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2_2 = uiaxes(app.OfflineEyeTrackingTab);
            title(app.UIAxes2_2, '')
            xlabel(app.UIAxes2_2, '')
            ylabel(app.UIAxes2_2, '')
            app.EyeAnalysisTab = uitab(app.TabGroup);
            app.EyeAnalysisTab.Title = 'Eye Analysis';
            app.findmagscaleVelButton = uibutton(app.EyeAnalysisTab, 'push');
            app.findmagscaleVelButton.ButtonPushedFcn = createCallbackFcn(app, @findmagscaleVelButtonPushed, true);
            app.findmagscaleVelButton.Text = 'findmagscaleVel';
            app.MagnetThreshEditFieldLabel = uilabel(app.EyeAnalysisTab);
            app.MagnetThreshEditFieldLabel.Text = 'Magnet Thresh';
            app.MagnetThreshEditField = uieditfield(app.EyeAnalysisTab, 'numeric');
            app.MagnetThreshEditField.Value = 5;
            app.VideoThreshEditFieldLabel = uilabel(app.EyeAnalysisTab);
            app.VideoThreshEditFieldLabel.Text = 'Video Thresh';
            app.VideoThreshEditField = uieditfield(app.EyeAnalysisTab, 'numeric');
            app.VideoThreshEditField.Value = 10;
            app.CalibrationStimFrequencyEditFieldLabel = uilabel(app.EyeAnalysisTab);
            app.CalibrationStimFrequencyEditFieldLabel.Text = 'Calibration Stim Frequency';
            app.CalibrationStimFrequencyEditField = uieditfield(app.EyeAnalysisTab, 'numeric');
            app.CalibrationStimFrequencyEditField.Value = 1;
            app.LeftEyeCheckBox = uicheckbox(app.EyeAnalysisTab);
            app.LeftEyeCheckBox.Tooltip = {'If unchecked'; ' use right eye'};
            app.LeftEyeCheckBox.Text = 'Left Eye';
            app.LeftEyeCheckBox.Value = true;
            app.Camera2TimestampsCheckBox = uicheckbox(app.EyeAnalysisTab);
            app.Camera2TimestampsCheckBox.Tooltip = {'If unchecked'; ' use camera 1'};
            app.Camera2TimestampsCheckBox.Text = 'Camera 2 Timestamps';
            app.Camera2TimestampsCheckBox.Value = true;
            app.PLOT_DesaccededTracesButton = uibutton(app.EyeAnalysisTab, 'push');
            app.PLOT_DesaccededTracesButton.ButtonPushedFcn = createCallbackFcn(app, @PLOT_DesaccededTracesButtonPushed, true);
            app.PLOT_DesaccededTracesButton.Text = 'Plot Desacceded Traces';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = eyeCalibration

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end